classdef ClearCardio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClearCardioLabel           matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        ChoosesignalDropDown       matlab.ui.control.DropDown
        ChoosesignalDropDownLabel  matlab.ui.control.Label
        RPeakLabel                 matlab.ui.control.Label
        HRLabel                    matlab.ui.control.Label
        HRButton                   matlab.ui.control.Button
        DetectButton               matlab.ui.control.Button
        FilterButton               matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        SignalPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RawSignals          % cell array for any number of signals
    FilteredSignals     % cell array for filtered signals
    RPeaks              % cell array for R‑peak indices
    AxesList            % handles to dynamically created axes
    HeartRate            % numeric array or cell array of HR values
    TimeVectors         % cell array of time vectors
    SignalNames   
    SerialObj   % Arduino serial connection
    SerialTimer % Timer for continuous reading
    Buffer      % Rolling data buffer
    PlotHandle  % Handle to the plotted line% cell array of names for dropdown
    end
    
    methods (Access = private)
  
   % --- New function: startArduinoStream ---
function startArduinoStream(app)

    % Close any previous serial connections
    delete(serialportfind);
% --- Check if COM11 exists ---
    ports = serialportlist("available");
    targetPort = "COM11";

    if ~ismember(targetPort, ports)
        uialert(app.UIFigure, ...
            "No Arduino detected on COM11. Please select a valid signal source.", ...
            "Connection Error");
        return;  % exit gracefully
    end
    % Open Arduino port
    app.SerialObj = serialport("COM11", 115200);
    configureTerminator(app.SerialObj, "LF");
    flush(app.SerialObj);
    pause(2);  % allow Arduino reset

    % Initialise buffer
    bufferLength = 4000;
    app.Buffer = zeros(bufferLength,1);

    % Create plot identical to your script
    cla(app.UIAxes);
    app.PlotHandle = plot(app.UIAxes, 1:bufferLength, app.Buffer);
    ylim(app.UIAxes, [0 1023]);
    xlim(app.UIAxes, [1 bufferLength]);
    title(app.UIAxes, "Live ECG from Arduino");
    xlabel(app.UIAxes, "Time");
    ylabel(app.UIAxes, "Amplitude");

    % Timer to mimic while(true) loop
    app.SerialTimer = timer( ...
        'ExecutionMode','fixedRate', ...
        'Period', 0.002, ...   % 500 Hz sampling rate
        'TimerFcn', @(~,~)updateECGPlot(app));

    start(app.SerialTimer);
end


            function updateECGPlot(app)
    if app.SerialObj.NumBytesAvailable > 0
        line = readline(app.SerialObj);
        newSample = str2double(line);

        if ~isnan(newSample)
            app.Buffer = [app.Buffer(2:end); newSample];
            set(app.PlotHandle, 'YData', app.Buffer);
            drawnow limitrate;
        end
    end
end


function StartupFcn(app)
    % --- Style the ECG plot ---
    app.UIAxes.Color = [0.07 0.07 0.07];
    app.UIAxes.XColor = [0.8 0.8 0.8];
    app.UIAxes.YColor = [0.8 0.8 0.8];
    app.UIAxes.GridColor = [0.3 0.3 0.3];
    app.UIAxes.LineWidth = 1.2;
    app.UIAxes.XMinorGrid = "on";
    app.UIAxes.YMinorGrid = "on";
end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            choice = app.ChoosesignalDropDown.Value;
    signal = [];
    t = [];
    name = "";

    switch choice
        % --- Sample File ---
        case "Sample File"
            run('LoadSampleSignal.m');   % creates ecg_raw and t
            signal = ecg_raw;
            name = "Sample File";

        % --- Arduino ECG ---
        case "Arduino ECG"
            startArduinoStream(app);
            if isempty(app.Buffer)
                return;
            end
            signal = app.Buffer;
            Fs = 500;
            t = (0:length(signal)-1)/Fs;
            name = "Arduino ECG";

        % --- Browse File... ---
        case "Browse file..."
            [file, path] = uigetfile({'*.mat;*.csv;*.txt', ...
                                      'Signal Files (*.mat, *.csv, *.txt)'});
            if isequal(file,0)
                uialert(app.UIFigure, "No file selected.", "Load Cancelled");
                return;
            end

            fullpath = fullfile(path, file);
            [~,~,ext] = fileparts(fullpath);

            try
                switch lower(ext)
                    case '.mat'
                        data = load(fullpath);
                        fields = fieldnames(data);
                        signal = data.(fields{1});
                    case {'.csv', '.txt'}
                        signal = readmatrix(fullpath);
                    otherwise
                        uialert(app.UIFigure, "Unsupported file type.", "Load Error");
                        return;
                end
            catch
                uialert(app.UIFigure, "Error reading file. Check format.", "Load Error");
                return;
            end

            Fs = 500;
            t = (0:length(signal)-1)/Fs;
            name = file;

        % --- No valid selection ---
        otherwise
            uialert(app.UIFigure, "Please select a valid signal source.", "No Selection");
            return;
    end

    if isempty(signal)
        uialert(app.UIFigure, "Loaded signal is empty.", "Load Error");
        return;
    end

    % --- Store and plot ---
    app.RawSignals{end+1} = signal;
    app.TimeVectors{end+1} = t;
    app.SignalNames{end+1} = name;

    if ~any(strcmp(app.ChoosesignalDropDown.Items, name))
        app.ChoosesignalDropDown.Items{end+1} = name;
    end

    delete(app.SignalPanel.Children);
    ax = uiaxes(app.SignalPanel);
    plot(ax, t, signal);
    title(ax, "Raw ECG Signal – " + name);
    xlabel(ax, "Time (s)");
    ylabel(ax, "Amplitude");


        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
    % Check if raw signals exist
    if isempty(app.RawSignals)
        uialert(app.UIFigure, 'Please load signals first using the Load Signals button.', 'No Signals Loaded');
        return;
    end
    % Sampling frequency
    Fs = 500;

    % Get raw signal from app
    ecg_raw = app.RawSignals{1};

    % Remove DC offset
    ecg_raw = ecg_raw - mean(ecg_raw);

    % --- Notch filter at 50 Hz ---
    wo = 50 / (Fs/2);      % Normalised frequency
    bw = wo / 35;          % Bandwidth
    [b_notch, a_notch] = iirnotch(wo, bw);
    ecg_notched = filtfilt(b_notch, a_notch, ecg_raw);

    % --- Band‑pass filter (0.5–40 Hz) ---
    low_cutoff = 0.5;
    high_cutoff = 40;
    [b_bp, a_bp] = butter(4, [low_cutoff high_cutoff] / (Fs/2), 'bandpass');
    ecg_filtered = filtfilt(b_bp, a_bp, ecg_notched);

    % Store filtered signal in app
    app.FilteredSignals = ecg_filtered;

    % Clear old plots
    delete(app.SignalPanel.Children)

    % Plot filtered ECG
    ax = uiaxes(app.SignalPanel);
    plot(ax, ecg_filtered);
    title(ax, 'Filtered ECG Signal');
    xlabel(ax, 'Samples');
    ylabel(ax, 'Amplitude');

        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
       % Check if filtered signal exists
    if isempty(app.FilteredSignals)
        uialert(app.UIFigure, ...
            'Please filter the signals first using the Filter Signals button.', ...
            'No Filtered Signal Available');
        return;
    end
            % Sampling frequency
    Fs = 500;

    % Get filtered signal
    ecg_filtered = app.FilteredSignals;

    % --- R‑peak detection ---
    min_peak_distance = round(0.4 * Fs);           % ≈ 150 bpm max
    min_peak_height = 0.5 * max(ecg_filtered);     % 50 % of max amplitude

    [pks, locs] = findpeaks(ecg_filtered, ...
        'MinPeakDistance', min_peak_distance, ...
        'MinPeakHeight', min_peak_height);

    % Store peak indices
    app.RPeaks = locs;

    % Clear old plots
    delete(app.SignalPanel.Children)

    % Plot filtered ECG with peaks
    ax = uiaxes(app.SignalPanel);
    plot(ax, ecg_filtered); hold(ax, 'on');
    plot(ax, locs, ecg_filtered(locs), 'ro', 'MarkerSize', 6, 'LineWidth', 1.2);
    title(ax, 'Detected R‑Peaks');
    xlabel(ax, 'Samples');
    ylabel(ax, 'Amplitude');

    % Update label
    app.RPeakLabel.Text = sprintf('R‑Peak detection complete (%d peaks found)', numel(locs));

        end

        % Button pushed function: HRButton
        function HRButtonPushed(app, event)
     % Check if R‑peaks exist
    if isempty(app.RPeaks)
        uialert(app.UIFigure, ...
            'Please detect R‑peaks first using the R‑Peak Detection button.', ...
            'No R‑Peaks Detected');
        return;
    end

    % Sampling frequency
    Fs = 500;

    % Convert R‑peak indices to time (seconds)
    R_times = app.RPeaks / Fs;

    % Compute RR intervals
    RR_intervals = diff(R_times);   % seconds

    % Compute average heart rate
    HR = 60 / mean(RR_intervals);   % bpm

    % Store heart rate in app
    app.HeartRate = HR;

    % Update label text
    app.HRLabel.Text = sprintf('Heart Rate: %.1f bpm', HR);

    % Optional: display number of peaks in Command Window
    fprintf('Average Heart Rate = %.1f bpm\n', HR);
    fprintf('Number of R‑peaks detected = %d\n', numel(app.RPeaks));


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           
    if ~isempty(app.SerialTimer)
        stop(app.SerialTimer);
        delete(app.SerialTimer);
    end
    clear app.SerialObj;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalPanel = uipanel(app.UIFigure);
            app.SignalPanel.Title = 'Signal';
            app.UIAxes = uiaxes(app.SignalPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'LoadSignals';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'FilterSignals';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'R-Peaks';
            app.HRButton = uibutton(app.UIFigure, 'push');
            app.HRButton.ButtonPushedFcn = createCallbackFcn(app, @HRButtonPushed, true);
            app.HRButton.Text = 'Heart-Rate';
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = 'Heart Rate -';
            app.RPeakLabel = uilabel(app.UIFigure);
            app.RPeakLabel.Text = {''; ''};
            app.ChoosesignalDropDownLabel = uilabel(app.UIFigure);
            app.ChoosesignalDropDownLabel.Text = 'Choose signal';
            app.ChoosesignalDropDown = uidropdown(app.UIFigure);
            app.ChoosesignalDropDown.Items = {'Sample File', 'Arduino ECG', 'Browse file...'};
            app.ChoosesignalDropDown.Value = 'Sample File';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop streaming (Arduino)';
            app.ClearCardioLabel = uilabel(app.UIFigure);
            app.ClearCardioLabel.Text = 'ClearCardio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClearCardio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end